classdef intenstine_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Original_plot                   matlab.ui.control.UIAxes
        Threshold_image                 matlab.ui.control.UIAxes
        ThreshsliderLabel               matlab.ui.control.Label
        Threshold_slider                matlab.ui.control.Slider
        LoadImage                       matlab.ui.control.Button
        AutoThresholdButton             matlab.ui.control.Button
        ThreshValueEditFieldLabel       matlab.ui.control.Label
        ThreshValueEditField            matlab.ui.control.NumericEditField
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        PlayMovieButton                 matlab.ui.control.Button
        CurrentFrameEditFieldLabel      matlab.ui.control.Label
        CurrentFrameEditField           matlab.ui.control.NumericEditField
        Kymograph                       matlab.ui.control.UIAxes
        MakeKymoButton                  matlab.ui.control.Button
        SaveKymoButton                  matlab.ui.control.Button
        DownsamplingratioDropDownLabel  matlab.ui.control.Label
        DownsamplingratioDropDown       matlab.ui.control.DropDown
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
    end

    
   %modified August 4 2021. --added the check blocks to limit threshold to
   %0.05-0.95. Added checkblock to keep frame number above 0.
    properties (Access = private)
        input_file % Input File
        Image %the image
        boundary % list of boundary coordinates
        bounding_box %the bounding box
        buffer %size of the buffer around the boundary box
        BW % black and white image
        level %threshold level
        downsample_ratio %the ratio by which to downsample the kymo, i.e. 5, is 1:5
        tif_movie
        numframes
        frame_number
        image_diameter
        kymo_reduced
        kymo_exist
        level_changed
        first_kymo
    end


    methods (Access = private)
        
        function thresh_filt(app)
            app.BW = imbinarize(app.Image, app.level); %threshold at level
            app.BW = bwareafilt(app.BW, 1); %show only biggest object
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImage
        function LoadImageButtonPushed(app, event)
            app.frame_number = 1;
            
            [file,path]=uigetfile('*.*', 'Select the image file'); %open dialog box to get file
            if (ischar(file)) %Check that the dialog box wasn't closed without picking a file
                app.input_file = strcat(path, file);
                [app.tif_movie, app.numframes] = Movie_import(app.input_file);
                
                app.Image = app.tif_movie(:,:,app.frame_number);
                
                imshow(app.Image, 'parent', app.Original_plot);
                set(app.Original_plot, 'visible', 'off');
                app.FrameSlider.Limits=[0,app.numframes]; %change slider to have the number of frames of the image
                app.buffer = 10; %load the buffer size from the get-go
                app.level = 0.5; %initialize level just so things don't crash later
                    thresh_filt(app);  %call the function that thresholds and filters all but the largest object
                    [app.bounding_box, app.boundary] = object_finder(app.BW,app.buffer);
                    
                    imshow(app.BW, 'parent', app.Threshold_image);
                    
                    hold(app.Threshold_image);
                    plot(app.Threshold_image, app.boundary(:,2), app.boundary(:,1), 'g', 'LineWidth', 2);
                    plot(app.Threshold_image, app.bounding_box(:,2), app.bounding_box(:,1), 'r', 'LineWidth', 2);
                    set(app.Threshold_image, 'visible', 'off');
                    hold(app.Threshold_image,"off")
                    app.ThreshValueEditField.Value = app.level;
                    app.Threshold_slider.Value = app.level;
                    app.DownsamplingratioDropDown.Value='16.7';
                    app.downsample_ratio = app.DownsamplingratioDropDown.Value;
                app.kymo_exist = false;
                app.level_changed=0; %initalize to zero
                app.FileNameEditField.Value = file;
            end
        end

        % Button pushed function: AutoThresholdButton
        function AutoThresholdButtonPushed(app, event)
            
            app.Image = app.tif_movie(:,:,app.frame_number);
            app.level = graythresh(app.Image);
            
            thresh_filt(app);  %call the function that thresholds and filters all but the largest object
            
            imshow(app.BW, 'parent', app.Threshold_image);
            set(app.Threshold_image, 'visible', 'off');
            
             [app.bounding_box, app.boundary] = object_finder(app.BW,app.buffer);
            
             hold(app.Threshold_image);
              plot(app.Threshold_image, app.boundary(:,2), app.boundary(:,1), 'g', 'LineWidth', 2);             %plot around the object in green
              plot(app.Threshold_image, app.bounding_box(:,2), app.bounding_box(:,1), 'r', 'LineWidth', 2);     %plot around the object in a red bounding box with a buffer.  
             hold(app.Threshold_image,"off")
             
             app.ThreshValueEditField.Value = app.level;
             app.Threshold_slider.Value = app.level;
%             
        end

        % Value changed function: ThreshValueEditField
        function ThreshValueEditFieldValueChanged(app, event)
            
            value = app.ThreshValueEditField.Value;
                            %write a check block so that the threshold doesn't go to
                %the limits
                if value == 1
                    app.level = 0.95;
                elseif value == 0
                    app.level = 0.05;
                else 
                    app.level = value;
                end
            %app.level=value;
            
                thresh_filt(app);  %call the function that thresholds and filters all but the largest object
                [app.bounding_box, app.boundary] = object_finder(app.BW,app.buffer);
                
                imshow(app.BW, 'parent', app.Threshold_image);       
                set(app.Threshold_image, 'visible', 'off');
           
             
              hold(app.Threshold_image);
              plot(app.Threshold_image, app.boundary(:,2), app.boundary(:,1), 'g', 'LineWidth', 2);
              plot(app.Threshold_image, app.bounding_box(:,2), app.bounding_box(:,1), 'r', 'LineWidth', 2);
              
              hold(app.Threshold_image,"off");
             app.ThreshValueEditField.Value = app.level;
             app.Threshold_slider.Value = app.level;
        end

        % Value changed function: Threshold_slider
        function Threshold_sliderValueChanged(app, event)
                %cla(app.Threshold_image, 'reset');
                value = app.Threshold_slider.Value;
                %write a check block so that the threshold doesn't go to
                %the limits
                if value == 1
                    app.level = 0.95;
                elseif value == 0
                    app.level = 0.05;
                else 
                    app.level = value;
                end
                %app.level = value;
                
            
                thresh_filt(app);  %call the function that thresholds and filters all but the largest object
                [app.bounding_box, app.boundary] = object_finder(app.BW,app.buffer);
                
                imshow(app.BW, 'parent', app.Threshold_image);
              
              hold(app.Threshold_image);
              plot(app.Threshold_image, app.boundary(:,2), app.boundary(:,1), 'g', 'LineWidth', 2);
              plot(app.Threshold_image, app.bounding_box(:,2), app.bounding_box(:,1), 'r', 'LineWidth', 2);
              set(app.Threshold_image, 'visible', 'off');
              hold(app.Threshold_image,"off");
              app.ThreshValueEditField.Value = app.level; %change the edit field to the value of the treshold slider
        end

        % Button pushed function: PlayMovieButton
        function PlayMovieButtonPushed(app, event)
            implay(app.tif_movie); %open window to play movie
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
                value = app.FrameSlider.Value;
                
                % determine which discrete option the current value is closest to.
                [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
                % move the slider to that option
                event.Source.Value = event.Source.MajorTicks(minIdx);
                % Override the selected value if you plan on using it within this function
                value = event.Source.MajorTicks(minIdx);
                %matlab counts from 1, so will be an error on frame zero.
                if value == 0
                    app.frame_number = 1;
                else
                    app.frame_number = value;
                end
            %app.frame_number=value;
            app.Image = app.tif_movie(:,:,app.frame_number);
            imshow(app.Image, 'parent', app.Original_plot);
            set(app.Original_plot, 'visible', 'off');
            
            %update threshold image
                thresh_filt(app);  %call the function that thresholds and filters all but the largest object
                [app.bounding_box, app.boundary] = object_finder(app.BW,app.buffer);
                
                imshow(app.BW, 'parent', app.Threshold_image);
              
              hold(app.Threshold_image);
              plot(app.Threshold_image, app.boundary(:,2), app.boundary(:,1), 'g', 'LineWidth', 2);
              plot(app.Threshold_image, app.bounding_box(:,2), app.bounding_box(:,1), 'r', 'LineWidth', 2);
              set(app.Threshold_image, 'visible', 'off');
              hold(app.Threshold_image,"off");
            
          
            app.CurrentFrameEditField.Value = app.frame_number;
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: CurrentFrameEditField
        function CurrentFrameEditFieldValueChanged(app, event)
        
         
        value = app.CurrentFrameEditField.Value;
       
        
                if value == 0
                    app.frame_number = 1;
                elseif value > app.numframes
                    app.frame_number = app.numframes-1;
                    app.CurrentFrameEditField.Value = app.frame_number;
                else
                    app.frame_number = value;
                end
            %app.frame_number=value;
            
            app.Image = app.tif_movie(:,:,app.frame_number);
            imshow(app.Image, 'parent', app.Original_plot);
            set(app.Original_plot, 'visible', 'off');
            
            %update threshold image
                thresh_filt(app);  %call the function that thresholds and filters all but the largest object
                [app.bounding_box, app.boundary] = object_finder(app.BW,app.buffer);
                
                imshow(app.BW, 'parent', app.Threshold_image);
              
              hold(app.Threshold_image);
              plot(app.Threshold_image, app.boundary(:,2), app.boundary(:,1), 'g', 'LineWidth', 2);
              plot(app.Threshold_image, app.bounding_box(:,2), app.bounding_box(:,1), 'r', 'LineWidth', 2);
              set(app.Threshold_image, 'visible', 'off');
              hold(app.Threshold_image,"off");
              
              
        app.FrameSlider.Value = app.frame_number;
        
            
        end

        % Button pushed function: MakeKymoButton
        function MakeKymoButtonPushed(app, event)
           cla(app.Kymograph, 'reset')
        if (app.level ~= app.level_changed) 
            [all_boxes, all_boundaries] = get_boundaries(app.tif_movie, app.buffer, app.level);
            [~, ~, min_col, max_col] = biggest_bounding_box(all_boxes);
            app.image_diameter = Make_kymo(app.numframes, min_col, max_col, all_boundaries);
            app.kymo_exist = true; %set kymo exist to true
            
        end
           app.level_changed = app.level; 
            %shrink the image
            fun=@(block_struct) mean(block_struct.data(:));
            app.kymo_reduced = blockproc(app.image_diameter, [str2num(app.downsample_ratio),1], fun); %downsample the kymograph by the amount from the downsampling box
            
            hold(app.Kymograph);
            imshow(app.kymo_reduced,[ ],'parent', app.Kymograph);         
            set(app.Kymograph, 'visible', 'off');
            hold(app.Kymograph, 'off');
           
        end

        % Button pushed function: SaveKymoButton
        function SaveKymoButtonPushed(app, event)
        %save image as 8 bit or 16 bit depending on the max diameter
        if app.kymo_exist
            if max(max(app.image_diameter)) < 255
                kymo_full = uint8(app.image_diameter);
                kymo_small = uint8(app.kymo_reduced);
            else
                kymo_full = uint16(app.image_diameter);
                kymo_small = uint16(app.kymo_reduced);
            end
        
        
                filter = {'*.tif'}; 
                [savefile, savepath]=uiputfile(filter,'Pick location to save file');
                if exist('savefile', 'var')==1 %check if there is a file that exists to save to
                    [savepath, savename, ext] = fileparts(savefile);
                    savename_full = strcat(savepath, savename, '_full', ext);
                    savename_reduced = strcat(savepath, savename, "_down_sampled_",app.downsample_ratio,ext); %save the file with the downsample ratio
                    savename_csv=strcat(savepath, savename, '.csv');
                    imwrite(kymo_full, savename_full); %saves diameter map as a tiff file without changing the contrast, or changing the picture
                    imwrite(kymo_small, savename_reduced);
                    writematrix(app.image_diameter, savename_csv); %save the data as a csv file
                end
        
        else
            k = msgbox('Please Generate a Kymograph before saving. Cheers!');
        end

        end

        % Value changed function: DownsamplingratioDropDown
        function DownsamplingratioDropDownValueChanged(app, event)
            value = app.DownsamplingratioDropDown.Value;
            app.downsample_ratio=value; %change the downsampling ratio to whatever is picked
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Original_plot = uiaxes(app.UIFigure);
            title(app.Original_plot, 'Original Image')
            app.Threshold_image = uiaxes(app.UIFigure);
            title(app.Threshold_image, 'Threshold Image')
            app.ThreshsliderLabel = uilabel(app.UIFigure);
            app.ThreshsliderLabel.Text = {'Thresh'; 'slider'};
            app.Threshold_slider = uislider(app.UIFigure);
            app.Threshold_slider.Limits = [0 1];
            app.Threshold_slider.ValueChangedFcn = createCallbackFcn(app, @Threshold_sliderValueChanged, true);
            app.LoadImage = uibutton(app.UIFigure, 'push');
            app.LoadImage.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImage.Text = 'Load Image';
            app.AutoThresholdButton = uibutton(app.UIFigure, 'push');
            app.AutoThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AutoThresholdButtonPushed, true);
            app.AutoThresholdButton.Text = 'AutoThreshold';
            app.ThreshValueEditFieldLabel = uilabel(app.UIFigure);
            app.ThreshValueEditFieldLabel.Text = 'Thresh Value';
            app.ThreshValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThreshValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshValueEditFieldValueChanged, true);
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [0 10];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [];
            app.PlayMovieButton = uibutton(app.UIFigure, 'push');
            app.PlayMovieButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMovieButtonPushed, true);
            app.PlayMovieButton.Text = 'Play Movie';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.Kymograph = uiaxes(app.UIFigure);
            title(app.Kymograph, 'Kymograph')
            xlabel(app.Kymograph, 'X')
            ylabel(app.Kymograph, 'Y')
            app.MakeKymoButton = uibutton(app.UIFigure, 'push');
            app.MakeKymoButton.ButtonPushedFcn = createCallbackFcn(app, @MakeKymoButtonPushed, true);
            app.MakeKymoButton.Text = 'Make Kymo';
            app.SaveKymoButton = uibutton(app.UIFigure, 'push');
            app.SaveKymoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKymoButtonPushed, true);
            app.SaveKymoButton.Text = 'Save Kymo';
            app.DownsamplingratioDropDownLabel = uilabel(app.UIFigure);
            app.DownsamplingratioDropDownLabel.Text = {'Downsampling'; 'ratio'};
            app.DownsamplingratioDropDown = uidropdown(app.UIFigure);
            app.DownsamplingratioDropDown.Items = {'5', '10', '15', '16.7', '20', '25', '30'};
            app.DownsamplingratioDropDown.ValueChangedFcn = createCallbackFcn(app, @DownsamplingratioDropDownValueChanged, true);
            app.DownsamplingratioDropDown.Value = '5';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = intenstine_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end